classdef siscan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        AcquisitionTab            matlab.ui.container.Tab
        SINGLESHOTDSCANLabel      matlab.ui.control.Label
        InitializeButton          matlab.ui.control.Button
        SingleImageButton         matlab.ui.control.Button
        ExitCameraButton          matlab.ui.control.Button
        ExposuremsSpinnerLabel    matlab.ui.control.Label
        ExposuremsSpinner         matlab.ui.control.Spinner
        UIAxes                    matlab.ui.control.UIAxes
        FramerateSpinnerLabel     matlab.ui.control.Label
        FramerateSpinner          matlab.ui.control.Spinner
        ColormapDropDownLabel     matlab.ui.control.Label
        ColormapDropDown          matlab.ui.control.DropDown
        CONTROLSLabel             matlab.ui.control.Label
        INDICATORSLabel           matlab.ui.control.Label
        ExposuremsEditFieldLabel  matlab.ui.control.Label
        ExposuremsEditField       matlab.ui.control.NumericEditField
        LiveViewButton            matlab.ui.control.StateButton
        SaveScanButton            matlab.ui.control.Button
        TreatmentTab              matlab.ui.container.Tab
        RetrievalTab              matlab.ui.container.Tab
    end


    properties (Access = private)
        
        IDScam; % Camera class
        img % image class
        vid %for live input
        cmp %colormap class
     
    end


    methods (Access = private)

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)

                app.InitializeButton.FontColor = 'green';
                app.IDScam = IDSCamera('4103393584');
                app.IDScam.init();
                app.ExposuremsSpinner.Enable = 'on';
        end

        % Button pushed function: SingleImageButton
        function SingleImageButtonPushed(app, event)
                app.IDScam.getImageAsyncStartCam();
                app.img = app.IDScam.getImageAsyncEndCam();
                imshow(app.img,'Parent',app.UIAxes,'colormap',colormap(app.UIAxes,jet))
        end

        % Button pushed function: ExitCameraButton
        function ExitCameraButtonPushed(app, event)
            app.IDScam.delete();
            app.InitializeButton.FontColor = 'k';
            
        end

        % Value changed function: ExposuremsSpinner
        function ExposuremsSpinnerValueChanged(app, event)
            value = app.ExposuremsSpinner.Value;
            app.IDScam.setExposure(value*1e-3)
            fprintf('Exposure set to %.1f ms.\n',value)
            expTime = getExposure(app.IDScam);
            app.ExposuremsEditField.Value = expTime*1e3;
        end

        % Value changed function: LiveViewButton
        function LiveViewButtonValueChanged(app, event)
        
            
   %live view through .net implementation         
             app.IDScam.startLiveView();
             app.LiveViewButton.FontColor = 'r';
             value = app.LiveViewButton.Value;
             
             while value
                 if ~value
                      app.IDScam.stopLiveView();
                      
                     break
                 end
                app.img = app.IDScam.extractImage(); 
                imshow(app.img,'Parent',app.UIAxes,'colormap',colormap(app.UIAxes,jet));  
%                 expTime = getExposure(app.IDScam);
                pause(1e-5);
                value = app.LiveViewButton.Value;
             end
              app.LiveViewButton.FontColor = 'k';
%             
        end

        % Value changed function: FramerateSpinner
        function FramerateSpinnerValueChanged(app, event)
            value = app.FramerateSpinner.Value;
            app.IDScam.setFramerate(value)
            fprintf('Framerate set to %.1f Hz.\n',value)
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
%             value = app.ColormapDropDown.Value;
%             switch value
%                 case 'Monochrome'
%                     app.UIAxes.colormap('default')
%                 case 'Jet'
%                     app.UIAxes.colormap('spring')
%             end
        end

        % Button pushed function: SaveScanButton
        function SaveScanButtonPushed(app, event)
            filter = {'*.png';'*.jpg';'*.fig';'*.bmp';'*.*'};
            [file,path] = uiputfile(filter,'Save Scan');
            
            if isequal(file,0) || isequal(path,0)
                 disp('Cancelled.')
            else
              disp('Dscan saved')
            end
            dscan = fullfile(path,file);
            imwrite(app.img,jet(255),dscan); %replace later by colormap (cmp class)
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AcquisitionTab = uitab(app.TabGroup);
            app.AcquisitionTab.Title = 'Acquisition';
            app.SINGLESHOTDSCANLabel = uilabel(app.AcquisitionTab);
            app.SINGLESHOTDSCANLabel.Text = 'SINGLE-SHOT DSCAN';
            app.InitializeButton = uibutton(app.AcquisitionTab, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.SingleImageButton = uibutton(app.AcquisitionTab, 'push');
            app.SingleImageButton.ButtonPushedFcn = createCallbackFcn(app, @SingleImageButtonPushed, true);
            app.SingleImageButton.Text = 'Single Image';
            app.ExitCameraButton = uibutton(app.AcquisitionTab, 'push');
            app.ExitCameraButton.ButtonPushedFcn = createCallbackFcn(app, @ExitCameraButtonPushed, true);
            app.ExitCameraButton.Text = 'Exit Camera';
            app.ExposuremsSpinnerLabel = uilabel(app.AcquisitionTab);
            app.ExposuremsSpinnerLabel.Text = 'Exposure, ms';
            app.ExposuremsSpinner = uispinner(app.AcquisitionTab);
            app.ExposuremsSpinner.ValueChangedFcn = createCallbackFcn(app, @ExposuremsSpinnerValueChanged, true);
            app.UIAxes = uiaxes(app.AcquisitionTab);
            title(app.UIAxes, 'Scan')
            app.FramerateSpinnerLabel = uilabel(app.AcquisitionTab);
            app.FramerateSpinnerLabel.Text = 'Framerate';
            app.FramerateSpinner = uispinner(app.AcquisitionTab);
            app.FramerateSpinner.ValueChangedFcn = createCallbackFcn(app, @FramerateSpinnerValueChanged, true);
            app.ColormapDropDownLabel = uilabel(app.AcquisitionTab);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.AcquisitionTab);
            app.ColormapDropDown.Items = {'Monochrome', 'Jet', 'Other', 'Option 4'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'Monochrome';
            app.CONTROLSLabel = uilabel(app.AcquisitionTab);
            app.CONTROLSLabel.Text = 'CONTROLS';
            app.INDICATORSLabel = uilabel(app.AcquisitionTab);
            app.INDICATORSLabel.Text = 'INDICATORS';
            app.ExposuremsEditFieldLabel = uilabel(app.AcquisitionTab);
            app.ExposuremsEditFieldLabel.Text = 'Exposure, ms';
            app.ExposuremsEditField = uieditfield(app.AcquisitionTab, 'numeric');
            app.ExposuremsEditField.Editable = 'off';
            app.LiveViewButton = uibutton(app.AcquisitionTab, 'state');
            app.LiveViewButton.ValueChangedFcn = createCallbackFcn(app, @LiveViewButtonValueChanged, true);
            app.LiveViewButton.Text = 'Live View';
            app.SaveScanButton = uibutton(app.AcquisitionTab, 'push');
            app.SaveScanButton.ButtonPushedFcn = createCallbackFcn(app, @SaveScanButtonPushed, true);
            app.SaveScanButton.Text = 'Save Scan';
            app.TreatmentTab = uitab(app.TabGroup);
            app.TreatmentTab.Title = 'Treatment';
            app.RetrievalTab = uitab(app.TabGroup);
            app.RetrievalTab.Title = 'Retrieval';
        end
    end

    methods (Access = public)

        % Construct app
        function app = siscan

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end